classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartAnalysisButton     matlab.ui.control.Button
        AnalysisDropdown        matlab.ui.control.DropDown
        TypanalzyDropDownLabel  matlab.ui.control.Label
        InfoTextArea            matlab.ui.control.TextArea
        AudioFileInfoLabel      matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        AnalysisAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        audioData % Uchováva audio dáta
        sampleRate % Uchováva vzorkovaciu frekvenciu
        selectedAnalysisType = "Waveform" % Typ vybranej analýzy
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Otvorenie dialógového okna na výber súboru
            [file, path] = uigetfile({'*.wav;*.mp3', 'Audio Files (*.wav, *.mp3)'}, 'Vyberte audio súbor');
            
            % Kontrola, či bol súbor vybraný
            if isequal(file, 0)
                disp('Načítanie súboru zrušené.');
                return;
            else
                fullPath = fullfile(path, file);
                disp(['Načítaný súbor: ', fullPath]);
                
                % Načítanie zvukového súboru
                [data, fs] = audioread(fullPath);
                
                % Zobrazenie základných informácií o súbore
                app.InfoTextArea.Value = {['Cesta k súboru: ' fullPath]; 
                                          ['Vzorkovacia frekvencia: ' num2str(fs) ' Hz']; 
                                          ['Dĺžka (vzorky): ' num2str(length(data))]};
        
                % Uloženie dát pre ďalšiu analýzu
                app.audioData = data;
                app.sampleRate = fs;
            end
        end

        % Value changed function: AnalysisDropdown
        function analysisDropdownValueChanged(app, event)
            selectedAnalysis = app.AnalysisDropdown.Value;
            
            switch selectedAnalysis
                case 'Waveform'
                    % Kód pre nastavenie analýzy na vlnovú formu
                    app.selectedAnalysisType = 'Waveform';
                case 'Frequency'
                    % Kód pre nastavenie analýzy na frekvenciu
                    app.selectedAnalysisType = 'Frequency';
            end
            
        end

        % Button pushed function: StartAnalysisButton
        function startAnalysisButton(app, event)
            
            % Skontrolovať, či boli audio dáta načítané
            if isempty(app.audioData)
                % Zobraz chybovú správu, ak nie sú žiadne dáta
                uialert(app.UIFigure, 'Nebol načítaný žiadny audio súbor.', 'Chyba');
                return; % Skonči funkciu
            end
            
            % Získanie typu vybranej analýzy
            selectedType = app.selectedAnalysisType;
        
            % Vykonanie analýzy podľa vybraného typu
            switch selectedType
                case 'Waveform'                   
                    % Vytvorenie inštancie WaveformAnalysis a spustenie analýzy
                    waveformAnalyzer = WaveformAnalysis(app.audioData);
                    waveformData = waveformAnalyzer.analyze(); % Získanie audio dát
            
                    % Vytvorenie časovej osi
                    timeAxis = (1:length(waveformData)) / app.sampleRate;
            
                    % Vyčistenie predchádzajúcich dát z osí
                    cla(app.AnalysisAxes);
            
                    % Vykreslenie vlnovej formy
                    plot(app.AnalysisAxes, timeAxis, waveformData);
                    axis(app.AnalysisAxes, 'tight');
                    title(app.AnalysisAxes, 'Vlnová forma');
                    xlabel(app.AnalysisAxes, 'Čas [s]');
                    ylabel(app.AnalysisAxes, 'Amplitúda');
                    grid(app.AnalysisAxes, 'on');

                    app.AnalysisAxes.Visible = true;
        
                case 'Frequency'
                    % Vytvorenie inštancie FrequencyAnalysis a spustenie analýzy
                    frequencyAnalyzer = FrequencyAnalysis(app.audioData);
                    frequencyResult = frequencyAnalyzer.analyze();
            
                    % Vyčistenie predchádzajúcich dát z osí
                    cla(app.AnalysisAxes);
            
                    % Vykreslenie frekvenčného spektra
                    plot(app.AnalysisAxes, frequencyResult.frequency, frequencyResult.magnitude);
                    axis(app.AnalysisAxes, 'tight');
                    title(app.AnalysisAxes, 'Frekvenčné spektrum');
                    xlabel(app.AnalysisAxes, 'Frekvencia [Hz]');
                    ylabel(app.AnalysisAxes, 'Magnitúda');

                    % Nastavenie rozsahu osí pre frekvenčnú analýzu
                    grid(app.AnalysisAxes, 'on');
            
                    app.AnalysisAxes.Visible = true;
        
                otherwise
                    % Ošetrenie nevybraného typu analýzy
                    uialert(app.UIFigure, 'Nebol vybraný typ analýzy.', 'Chyba');
            end
        
            % Tu môžeš pridať kód na zobrazenie výsledkov analýzy v GUI
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalysisAxes = uiaxes(app.UIFigure);
            title(app.AnalysisAxes, 'Title')
            xlabel(app.AnalysisAxes, 'X')
            ylabel(app.AnalysisAxes, 'Y')
            zlabel(app.AnalysisAxes, 'Z')
            app.AnalysisAxes.Visible = 'off';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load File';
            app.AudioFileInfoLabel = uilabel(app.UIFigure);
            app.AudioFileInfoLabel.Text = 'Audio File Info';
            app.InfoTextArea = uitextarea(app.UIFigure);
            app.InfoTextArea.Editable = 'off';
            app.TypanalzyDropDownLabel = uilabel(app.UIFigure);
            app.TypanalzyDropDownLabel.Text = 'Typ analýzy';
            app.AnalysisDropdown = uidropdown(app.UIFigure);
            app.AnalysisDropdown.Items = {'Vlnová forma', 'Frekvenčná analýza'};
            app.AnalysisDropdown.ItemsData = {'Waveform', 'Frequency'};
            app.AnalysisDropdown.ValueChangedFcn = createCallbackFcn(app, @analysisDropdownValueChanged, true);
            app.AnalysisDropdown.Value = 'Waveform';
            app.StartAnalysisButton = uibutton(app.UIFigure, 'push');
            app.StartAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @startAnalysisButton, true);
            app.StartAnalysisButton.Text = 'Spusti analýzu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end